classdef svr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModelEvaluationPanel            matlab.ui.container.Panel
        VisualizeButton_2               matlab.ui.control.Button
        TestErrorLabel                  matlab.ui.control.Label
        TrainErrorLabel                 matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        MetricsDropDown                 matlab.ui.control.DropDown
        MetricsDropDownLabel            matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ModelingPanel                   matlab.ui.container.Panel
        Gamma                           matlab.ui.control.EditField
        TrainModelButton                matlab.ui.control.Button
        ValidationDataButton            matlab.ui.control.Button
        DegreeDropDown                  matlab.ui.control.DropDown
        DegreeDropDownLabel             matlab.ui.control.Label
        TrainingDataButton              matlab.ui.control.Button
        Margin                          matlab.ui.control.EditField
        Regularization                  matlab.ui.control.EditField
        KernelDropDown                  matlab.ui.control.DropDown
        KernelDropDownLabel             matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        DataPreprocessingPanel          matlab.ui.container.Panel
        TrainingSizeEditField           matlab.ui.control.NumericEditField
        TrainingSizeLabel               matlab.ui.control.Label
        NormalizingmethodDropDown       matlab.ui.control.DropDown
        NormalizingmethodDropDownLabel  matlab.ui.control.Label
        VisualizethesplitButton         matlab.ui.control.Button
        TrainTestSplitButton            matlab.ui.control.Button
        ApplyNormalizationButton        matlab.ui.control.Button
        UploadDatasetPanel              matlab.ui.container.Panel
        TypeofdataDropDown              matlab.ui.control.DropDown
        TypeofdataDropDownLabel         matlab.ui.control.Label
        VisualizeButton                 matlab.ui.control.Button
        GenerateDataButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UploadDatasetButton             matlab.ui.control.Button
    end

    properties (Access = public)
        Data                    table
        NormalizedData          table
        TrainingData            table
        TestingData             table
        ModelData               struct
    end
      
    methods (Access = private)
                    
        function splitData(app)
            % Ensure data is available
            if isempty(app.Data)
                uialert(app.UIFigure, 'No dataset available for splitting.', 'Error');
                return;
            end
        
            % Get the split ratio from the UI (e.g., 0.8 for 80% training, 20% testing)
            splitRatio = app.TrainingSizeEditField.Value; % Assuming you have an edit field for the ratio
        
            % Ensure the split ratio is valid
            if splitRatio <= 0 || splitRatio > 1
                uialert(app.UIFigure, 'Split ratio must be between 0 and 1.', 'Error');
                return;
            end
            
            % Get the number of rows in the dataset
            numRows = size(app.Data, 1);
        
            % Shuffle the data
            shuffledIndices = randperm(numRows);
            shuffledData = app.Data(shuffledIndices, :);
        
            % Split the data into training and testing sets
            splitIndex = round(splitRatio * numRows);
            app.TrainingData = shuffledData(1:splitIndex, :);
            app.TestingData = shuffledData(splitIndex+1:end, :);
        
            % Notify the user
            uialert(app.UIFigure, sprintf('Data split into training (%d rows) and testing (%d rows).', ...
                size(app.TrainingData, 1), size(app.TestingData, 1)), 'Success', 'Icon', 'success');
        end
        
        function visualize(app)
            % Extract data from UITable
            data = app.UITable.Data;
        
            % Check if data is empty
            if isempty(data)
                uialert(app.UIFigure, 'No data available for visualization.', 'Error');
                return;
            end
        
            % Ensure data is numeric
            if ~isnumeric(data)
                % If data is in a table or cell format, convert it to a numeric array
                try
                    data = table2array(data); % If data is a table
                catch
                    try
                        data = cell2mat(data); % If data is a cell array
                    catch
                        uialert(app.UIFigure, 'Data is not numeric and cannot be plotted.', 'Error');
                        return;
                    end
                end
            end
        
            % Check if data has at least two columns (X and Y)
            if size(data, 2) < 2
                uialert(app.UIFigure, 'Data must have at least two columns (X and Y) for plotting.', 'Error');
                return;
            end
        
            % Clear existing plot
            cla(app.UIAxes);
            if size(data, 2) == 2
                % Plot the data
                scatter(app.UIAxes, data(:, 1), data(:, 2), 'filled');
                xlabel(app.UIAxes, 'X');
                ylabel(app.UIAxes, 'Y');
                title(app.UIAxes, 'Data Visualization');
                grid(app.UIAxes, 'on');
            else
                % Scatter plot of the data
                scatter3(app.UIAxes, data(:, 1), data(:, 2), data(:, 3), 'DisplayName', 'Data');
                hold(app.UIAxes, 'on');
                
                % Create a grid for the surface plot
                [X1, X2] = meshgrid(unique(data(:, 1)), unique(data(:, 2)));
                
                % Reshape or interpolate data(:, 3) into Z values for the grid
                Z = griddata(data(:, 1), data(:, 2), data(:, 3), X1, X2, 'linear');
                
                % Plot the surface
                surf(app.UIAxes, X1, X2, Z, 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'DisplayName', 'Data Surface');
                
                % Label axes
                xlabel(app.UIAxes, 'Feature 1');
                ylabel(app.UIAxes, 'Feature 2');
                zlabel(app.UIAxes, 'Target');
                legend(app.UIAxes, 'show');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadDatasetButton
        function UploadDatasetButtonPushed(app, event)
            [file, path] = uigetfile({'*.csv;*.mat', 'Data Files (*.csv, *.mat)'});
            if file
                fullPath = fullfile(path, file);
                app.Data = readtable(fullPath); % Load dataset
                app.UITable.Data = app.Data; % Display in UITable
            end
            % Visualizing the uploaded data
            visualize(app);
        end

        % Button pushed function: ApplyNormalizationButton
        function ApplyNormalizationButtonPushed(app, event)
            method = app.NormalizingmethodDropDown.Value; % This is the correct component name
            if strcmp(method, 'Min-Max')
                app.TestingData = (app.TestingData - min(app.TestingData)) ./ (max(app.TestingData) - min(app.TestingData));
                app.TrainingData = (app.TrainingData - min(app.TrainingData)) ./ (max(app.TrainingData) - min(app.TrainingData));
                app.NormalizedData = (app.Data - min(app.Data)) ./ (max(app.Data) - min(app.Data));
                app.UITable.Data = app.NormalizedData;
            elseif strcmp(method, 'Z-Score')
                app.TestingData = (app.TestingData - mean(app.TestingData)) ./ std(app.TestingData);
                app.TrainingData = (app.TrainingData - mean(app.TrainingData)) ./ std(app.TrainingData);
                app.NormalizedData = (app.Data - mean(app.Data)) ./ std(app.Data);
                app.UITable.Data = app.NormalizedData;
            end

            % Show confirmation
            uialert(app.UIFigure, 'Sample data normalized successfully!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: GenerateDataButton
        function GenerateDataButtonPushed(app, event)
            numPoints = 250;  % Number of data points
            x = linspace(-10, 10, numPoints)'; % Generate x values
            
            % Add some noise to make it more realistic
            scalingFactor = 1 + (5 - 1) * rand(); % Random scaling factor between 1 and 5
            noise = scalingFactor * randn(numPoints, 1); % Noise scaled by the scaling factor
            
            % Generate random slope (m) and intercept (b) for the linear relationship
            m = 1 + (10 - 1) * rand(); % Random slope between 1 and 10
            b = 1 + (5 - 1) * rand();  % Random intercept between 1 and 5
            method = app.TypeofdataDropDown.Value;
            if strcmp(method, "Linear")
                % Generate y values (y = mx + b + noise)
                y = m * x + b + noise;
                % Create a table with the data
                app.Data = table(x, y, 'VariableNames', {'X', 'Target'});
            elseif strcmp(method, "Polynomial")
                deg = randi([2, 5]);
                y = b + noise;
                while deg > 0
                    y = y + m *  x.^deg + 10 *deg* noise;
                    deg = deg - 1;
                end
                % Create a table with the data
                app.Data = table(x, y, 'VariableNames', {'X', 'Target'});
            elseif strcmp(method, "Sinusoidal")
                y = m * sin(x) + b + 0.5 * noise;
                % Create a table with the data
                app.Data = table(x, y, 'VariableNames', {'X', 'Target'});
            elseif strcmp(method, "Surprise")
                x = linspace(-10, 10, 10)
                f = @(x, y) sin(x.^2) + cos(y.^2);
                y = linspace(-10, 10, 10); 
                [X, Y] = meshgrid(x, y);  
                
                Z = f(X, Y);
                X = X(:);
                Y = Y(:);
                Z = Z(:);
                app.Data = table(X, Y, Z, 'VariableNames', {'X1', 'X2', 'Target'});
            end


            % Display data in the table
            app.UITable.Data = table2cell(app.Data);
            app.UITable.ColumnName = app.Data.Properties.VariableNames;
            
            % Visualizing the generated data
            visualize(app);
            
            % Show confirmation
            uialert(app.UIFigure, 'Sample data uploaded successfully!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            % Call visualize function
            visualize(app);
        end

        % Button pushed function: TrainTestSplitButton
        function TrainTestSplitButtonPushed(app, event)
            % Call the splitData function
            splitData(app);
    
            % Ensure training and testing data are available
            if isempty(app.TrainingData) || isempty(app.TestingData)
                uialert(app.UIFigure, 'No split data available for visualization.', 'Error');
                return;
            end
        
            % Get the number of rows in the training and testing sets
            numTrainingRows = size(app.TrainingData, 1);
            numTestingRows = size(app.TestingData, 1);
        
            % Create a cell array for the UITable data
            combinedData = [app.TrainingData; app.TestingData];
            app.UITable.Data = combinedData;
        
            % Clear existing styles from the UITable
            removeStyle(app.UITable);
        
            % Define styles for training and testing data
            trainingStyle = uistyle('BackgroundColor', [0.7, 0.7, 1]); % Light blue
            testingStyle = uistyle('BackgroundColor', [1, 0.7, 0.7]);  % Light red
        
            % Apply styles to the UITable
            addStyle(app.UITable, trainingStyle, 'row', 1:numTrainingRows); % Training data rows
            addStyle(app.UITable, testingStyle, 'row', (numTrainingRows+1):(numTrainingRows+numTestingRows)); % Testing data rows
        end

        % Button pushed function: VisualizethesplitButton
        function VisualizethesplitButtonPushed(app, event)

            X_train = app.TrainingData{:, 1:end-1};
            y_train = app.TrainingData{:, end};
            X_test = app.TestingData{:, 1:end-1};
            y_test = app.TestingData{:, end};
            % Ensure the number of features doesn't exceed 2 for visualization
            if size(X_train, 2) > 2
                uialert(app.UIFigure, 'Unfortunately, we can only visualize up to 2 features.', 'Error');
                return;
            end
            
            % Clear existing plot
            cla(app.UIAxes);
            % Plot for 1 feature
            if size(X_train, 2) == 1
                scatter(app.UIAxes, X_train, y_train, 'filled', 'DisplayName', 'Training Data');
                hold(app.UIAxes, 'on');
                scatter(app.UIAxes, X_test, y_test, 'filled', 'DisplayName', 'Testing Data', 'MarkerEdgeColor','red');
                xlabel(app.UIAxes, 'Feature');
                ylabel(app.UIAxes, 'Target');
                title(app.UIAxes, 'Training Test Split');
                legend(app.UIAxes, 'show');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                
            % Plot for 2 features
            elseif size(X_train, 2) == 2
                scatter3(app.UIAxes, X_train(:, 1), X_train(:, 2), y_train, 'filled', 'DisplayName', 'Training Data');
                hold(app.UIAxes, 'on');
                
                scatter3(app.UIAxes, X_test(:, 1), X_test(:, 2), y_test, 'filled', 'DisplayName', 'Testing Data', 'MarkerEdgeColor','red');
                xlabel(app.UIAxes, 'Feature 1');
                ylabel(app.UIAxes, 'Feature 2');
                zlabel(app.UIAxes, 'Target');
                title(app.UIAxes, 'Training Test Split');
                legend(app.UIAxes, 'show');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
            end
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
             % Ensure training and testing data are available
            if isempty(app.TrainingData) || isempty(app.TestingData)
                uialert(app.UIFigure, 'No training or testing data available.', 'Error');
                return;
            end
        
            % Extract training and testing data        
            X_train = table2array(app.TrainingData(:, 1:end-1)); % Features
            y_train = table2array(app.TrainingData(:, end));     % Target
            X_test = table2array(app.TestingData(:, 1:end-1));   % Features
            y_test = table2array(app.TestingData(:, end));       % Target
        
            % Validate hyperparameters
            C = str2double(app.Regularization.Value); % BoxConstraint
            epsilon = str2double(app.Margin.Value);   % Epsilon
            if isnan(C) || C < 0 || isnan(epsilon) || epsilon <= 0
                uialert(app.UIFigure, 'Please enter valid positive values for C and epsilon.', 'Error');
                return;
            end
        
            % Retrieve kernel and additional parameters
            kernel = app.KernelDropDown.Value;
            if strcmp(kernel, 'rbf')
                gamma = str2double(app.Gamma.Value); % Gamma for RBF kernel
                if isnan(gamma) || gamma <= 0
                    uialert(app.UIFigure, 'Gamma must be a positive numeric value.', 'Error');
                    return;
                end
                sigma = sqrt(2 / gamma); % Compute sigma from gamma
            elseif strcmp(kernel, 'Polynomial')
                deg = str2double(app.DegreeDropDown.Value); % Polynomial degree
                if isnan(deg) || deg <= 0
                    uialert(app.UIFigure, 'Degree must be a positive integer.', 'Error');
                    return;
                end
            end
        
            % Fit the model
            try
                if strcmp(kernel, 'Linear')
                    model = fitrsvm(X_train, y_train, ...
                                        "KernelFunction", kernel, ...
                                        "BoxConstraint", C, ...
                                        "Epsilon", epsilon);
                elseif strcmp(kernel, 'Polynomial')
                    model = fitrsvm(X_train, y_train, ...
                                        "KernelFunction", kernel, ...
                                        "PolynomialOrder", deg, ...
                                        "BoxConstraint", C, ...
                                        "Epsilon", epsilon);
                elseif strcmp(kernel, 'rbf')
                    model = fitrsvm(X_train, y_train, ...
                                        "KernelFunction", kernel, ...
                                        "BoxConstraint", C, ...
                                        "KernelScale", sigma, ...
                                        "Epsilon", epsilon);
                end
        
                % Generate predictions
                trainPredictions = predict(model, X_train);
                testPredictions = predict(model, X_test);
                
                % Store the model and predictions in a structure to use
                % them later in other functions
                app.ModelData = struct('Model', model, ...
                               'TrainPredictions', trainPredictions, ...
                               'TestPredictions', testPredictions);

                uialert(app.UIFigure, 'Model trained successfully!', 'Success', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, sprintf('Failed to train the model: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: TrainingDataButton
        function TrainingDataButtonPushed(app, event)
             if isempty(app.ModelData) || isempty(app.ModelData.TrainPredictions)
                uialert(app.UIFigure, 'You should train the model first', 'Error');
                return;
             end
            
            X_train = app.TrainingData{:, 1:end-1};
            y_train = app.TrainingData{:, end};
            trainPred = app.ModelData.TrainPredictions;
            

            if isempty(trainPred)
                uialert(app.UIFigure, 'You should train the model first', 'Error');
                return;
            end

            % Ensure the number of features doesn't exceed 2 for visualization
            if size(X_train, 2) > 2
                uialert(app.UIFigure, 'Unfortunately, we can only visualize up to 2 features.', 'Error');
                return;
            end
            
            % Clear existing plot
            cla(app.UIAxes);
            % Sort the data for smooth plotting
            [X_train_sorted, idx] = sort(X_train);
            trainPred_sorted = trainPred(idx);
            % Plot for 1 feature
            if size(X_train, 2) == 1
                scatter(app.UIAxes, X_train, y_train, 'filled', 'DisplayName', 'Data');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, X_train_sorted, trainPred_sorted, '-r', 'LineWidth', 2, 'DisplayName', 'Prediction');
                xlabel(app.UIAxes, 'Feature 1');
                ylabel(app.UIAxes, 'Target');
                title(app.UIAxes, 'Prediction on Training Set');
                legend(app.UIAxes, 'show');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                
            % Plot for 2 features
            elseif size(X_train, 2) == 2
                scatter3(app.UIAxes, X_train(:, 1), X_train(:, 2), y_train, 'filled', 'DisplayName', 'Data');
                hold(app.UIAxes, 'on');
                
                % Create a grid for the surface plot
                [X1, X2] = meshgrid(linspace(min(X_train(:, 1)), max(X_train(:, 1)), 50), ...
                                    linspace(min(X_train(:, 2)), max(X_train(:, 2)), 50));
                X_grid = [X1(:), X2(:)];
                Y_grid = predict(app.ModelData.Model, X_grid);
                
                % Plot the prediction surface
                surf(app.UIAxes, X1, X2, reshape(Y_grid, size(X1)), 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'DisplayName', 'Prediction Surface');
                xlabel(app.UIAxes, 'Feature 1');
                ylabel(app.UIAxes, 'Feature 2');
                zlabel(app.UIAxes, 'Target');
                title(app.UIAxes, 'Prediction on Training Set');
                legend(app.UIAxes, 'show');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
            end
        end

        % Button pushed function: ValidationDataButton
        function ValidationDataButtonPushed(app, event)
            if (isempty(app.ModelData) || isempty(app.ModelData.TestPredictions))
                uialert(app.UIFigure, 'You should train the model first', 'Error');
                return;
            end
            testPred = app.ModelData.TestPredictions;
            if (isempty(testPred))
                uialert(app.UIFigure, 'You should train the model first', 'Error');
            end

            X_test = app.TestingData{:, 1:end-1};
            y_test = app.TestingData{:, end};
       
            [X_test_sorted, idx] = sort(X_test);
            testPred_sorted = testPred(idx);
            % Ensure the number of features doesn't exceed 2 for visualization
            if size(X_test, 2) > 2
                uialert(app.UIFigure, 'Unfortunately, we can only visualize up to 2 features.', 'Error');
                return;
            end
            % Clear existing plot
            cla(app.UIAxes);
            
            % Plot for 1 feature
            if size(X_test, 2) == 1
                scatter(app.UIAxes, X_test, y_test, 'filled');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, X_test_sorted, testPred_sorted, '-r', 'LineWidth', 2);
                xlabel(app.UIAxes, 'X');
                ylabel(app.UIAxes, 'Y');
                title(app.UIAxes, 'Prediction on Training Set');
                legend(app.UIAxes, 'Data', 'Prediction');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
            elseif size(X_test, 2) == 2
                scatter3(app.UIAxes, X_test(:, 1), X_test(:, 2), y_test, 'filled', 'DisplayName', 'Data');
                hold(app.UIAxes, 'on');
                
                % Create a grid for the surface plot
                [X1, X2] = meshgrid(linspace(min(X_test(:, 1)), max(X_test(:, 1)), 50), ...
                                    linspace(min(X_test(:, 2)), max(X_test(:, 2)), 50));
                X_grid = [X1(:), X2(:)];
                Y_grid = predict(app.ModelData.Model, X_grid);
                
                % Plot the prediction surface
                surf(app.UIAxes, X1, X2, reshape(Y_grid, size(X1)), 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'DisplayName', 'Prediction Surface');
                xlabel(app.UIAxes, 'Feature 1');
                ylabel(app.UIAxes, 'Feature 2');
                zlabel(app.UIAxes, 'Target');
                title(app.UIAxes, 'Prediction on Training Set');
                legend(app.UIAxes, 'show');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
            end
        end

        % Value changed function: MetricsDropDown
        function MetricsDropDownValueChanged(app, event)
    % Get the selected metric from the dropdown
    selectedMetric = app.MetricsDropDown.Value;

    % Check if the model and predictions are available
    if isempty(app.ModelData) || isempty(app.ModelData.Model) || ...
       isempty(app.ModelData.TrainPredictions) || isempty(app.ModelData.TestPredictions)
        % Display NaN if no model or predictions are available
        app.EditField_2.Value = 'NaN'; % Training error
        app.EditField_3.Value = 'NaN'; % Testing error
        return;
    end

    % Extract true and predicted values for training and testing data
    y_train_true = app.TrainingData{:, end}; % True values for training data
    y_train_pred = app.ModelData.TrainPredictions; % Predicted values for training data

    y_test_true = app.TestingData{:, end}; % True values for testing data
    y_test_pred = app.ModelData.TestPredictions; % Predicted values for testing data

    % Calculate the selected metric for training and testing data
    switch selectedMetric
        case 'R²'
            % R-squared for training data
            train_metric = 1 - sum((y_train_true - y_train_pred).^2) / sum((y_train_true - mean(y_train_true)).^2);
            % R-squared for testing data
            test_metric = 1 - sum((y_test_true - y_test_pred).^2) / sum((y_test_true - mean(y_test_true)).^2);

        case 'MSE'
            % Mean Squared Error for training data
            train_metric = mean((y_train_true - y_train_pred).^2);
            % Mean Squared Error for testing data
            test_metric = mean((y_test_true - y_test_pred).^2);

        case 'MAE'
            % Mean Absolute Error for training data
            train_metric = mean(abs(y_train_true - y_train_pred));
            % Mean Absolute Error for testing data
            test_metric = mean(abs(y_test_true - y_test_pred));

        otherwise
            % Invalid selection
            train_metric = NaN;
            test_metric = NaN;
    end

    % Display the results in the EditFields
    app.EditField_2.Value = sprintf('%.4f', train_metric); % Training error
    app.EditField_3.Value = sprintf('%.4f', test_metric); % Testing error
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)

    % Check if the model and predictions are available
    if isempty(app.ModelData) || isempty(app.ModelData.TestPredictions)
        uialert(app.UIFigure, 'No model or predictions available. Please train the model first.', 'Error');
        return;
    end

    % Extract true and predicted values for testing data
    y_true = app.TestingData{:, end}; % True values
    y_pred = app.ModelData.TestPredictions; % Predicted values

    % Clear existing plot
    cla(app.UIAxes2);

    % Plot predicted vs true values
    scatter(app.UIAxes2, y_true, y_pred, 'b', 'filled'); % Blue dots for predictions
    hold(app.UIAxes2, 'on');

    % Plot the ideal line (y = x)
    min_val = min([y_true; y_pred]); % Minimum value for the ideal line
    max_val = max([y_true; y_pred]); % Maximum value for the ideal line
    plot(app.UIAxes2, [min_val, max_val], [min_val, max_val], 'r--', 'LineWidth', 2); % Red dashed line

    % Add labels and legend
    xlabel(app.UIAxes2, 'True Values');
    ylabel(app.UIAxes2, 'Predicted Values');
    title(app.UIAxes2, 'Predicted vs True Values');
    legend(app.UIAxes2, 'Predictions', 'Ideal', 'Location', 'northwest');
    grid(app.UIAxes2, 'on');
    hold(app.UIAxes2, 'off');
        end

        % Button pushed function: VisualizeButton_2
        function VisualizeButton_2Pushed(app, event)
       % Call the UIAxes2ButtonDown function to plot the graph
    UIAxes2ButtonDown(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadDatasetPanel = uipanel(app.UIFigure);
            app.UploadDatasetPanel.Title = 'Upload Dataset';
            app.UploadDatasetButton = uibutton(app.UploadDatasetPanel, 'push');
            app.UploadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDatasetButtonPushed, true);
            app.UploadDatasetButton.Text = 'Upload Dataset';
            app.UITable = uitable(app.UploadDatasetPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.Tag = 'UITable';
            app.GenerateDataButton = uibutton(app.UploadDatasetPanel, 'push');
            app.GenerateDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDataButtonPushed, true);
            app.GenerateDataButton.Text = 'Generate Data';
            app.VisualizeButton = uibutton(app.UploadDatasetPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.TypeofdataDropDownLabel = uilabel(app.UploadDatasetPanel);
            app.TypeofdataDropDownLabel.Text = 'Type of data';
            app.TypeofdataDropDown = uidropdown(app.UploadDatasetPanel);
            app.TypeofdataDropDown.Items = {'Linear', 'Polynomial', 'Sinusoidal', 'Surprise'};
            app.TypeofdataDropDown.Value = 'Linear';
            app.DataPreprocessingPanel = uipanel(app.UIFigure);
            app.DataPreprocessingPanel.Title = 'Data Preprocessing';
            app.ApplyNormalizationButton = uibutton(app.DataPreprocessingPanel, 'push');
            app.ApplyNormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyNormalizationButtonPushed, true);
            app.ApplyNormalizationButton.Text = 'Apply Normalization';
            app.TrainTestSplitButton = uibutton(app.DataPreprocessingPanel, 'push');
            app.TrainTestSplitButton.ButtonPushedFcn = createCallbackFcn(app, @TrainTestSplitButtonPushed, true);
            app.TrainTestSplitButton.Text = 'Train Test Split';
            app.VisualizethesplitButton = uibutton(app.DataPreprocessingPanel, 'push');
            app.VisualizethesplitButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizethesplitButtonPushed, true);
            app.VisualizethesplitButton.Text = 'Visualize the split';
            app.NormalizingmethodDropDownLabel = uilabel(app.DataPreprocessingPanel);
            app.NormalizingmethodDropDownLabel.Text = 'Normalizing method';
            app.NormalizingmethodDropDown = uidropdown(app.DataPreprocessingPanel);
            app.NormalizingmethodDropDown.Items = {'Min-Max', 'Z-score'};
            app.NormalizingmethodDropDown.Value = 'Min-Max';
            app.TrainingSizeLabel = uilabel(app.DataPreprocessingPanel);
            app.TrainingSizeLabel.Text = 'Training Size';
            app.TrainingSizeEditField = uieditfield(app.DataPreprocessingPanel, 'numeric');
            app.TrainingSizeEditField.Tag = 'TTS_Percentage';
            app.ModelingPanel = uipanel(app.UIFigure);
            app.ModelingPanel.Title = 'Modeling';
            app.UIAxes = uiaxes(app.ModelingPanel);
            title(app.UIAxes, 'Data with regression')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'UIAxe';
            app.EditField = uieditfield(app.ModelingPanel, 'text');
            app.EditField.Value = 'SVR';
            app.KernelDropDownLabel = uilabel(app.ModelingPanel);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.ModelingPanel);
            app.KernelDropDown.Items = {'Linear', 'Polynomial', 'rbf'};
            app.KernelDropDown.Value = 'Linear';
            app.Regularization = uieditfield(app.ModelingPanel, 'text');
            app.Regularization.Placeholder = 'C Regularization';
            app.Margin = uieditfield(app.ModelingPanel, 'text');
            app.Margin.Placeholder = 'Margin epsilon';
            app.TrainingDataButton = uibutton(app.ModelingPanel, 'push');
            app.TrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingDataButtonPushed, true);
            app.TrainingDataButton.Text = 'Training Data';
            app.DegreeDropDownLabel = uilabel(app.ModelingPanel);
            app.DegreeDropDownLabel.Text = 'Degree';
            app.DegreeDropDown = uidropdown(app.ModelingPanel);
            app.DegreeDropDown.Items = {'1', '2', '3', '4', '5', '6', '7'};
            app.DegreeDropDown.Value = '1';
            app.ValidationDataButton = uibutton(app.ModelingPanel, 'push');
            app.ValidationDataButton.ButtonPushedFcn = createCallbackFcn(app, @ValidationDataButtonPushed, true);
            app.ValidationDataButton.Text = 'Validation Data';
            app.TrainModelButton = uibutton(app.ModelingPanel, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.Gamma = uieditfield(app.ModelingPanel, 'text');
            app.Gamma.Placeholder = 'Gamma';
            app.ModelEvaluationPanel = uipanel(app.UIFigure);
            app.ModelEvaluationPanel.Title = 'Model Evaluation ';
            app.UIAxes2 = uiaxes(app.ModelEvaluationPanel);
            title(app.UIAxes2, 'Actual and predicted values')
            xlabel(app.UIAxes2, 'Actual value ')
            ylabel(app.UIAxes2, 'Predicted value')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.MetricsDropDownLabel = uilabel(app.ModelEvaluationPanel);
            app.MetricsDropDownLabel.Text = 'Metrics  : ';
            app.MetricsDropDown = uidropdown(app.ModelEvaluationPanel);
            app.MetricsDropDown.Items = {'R²', 'MSE', 'MAE'};
            app.MetricsDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricsDropDownValueChanged, true);
            app.MetricsDropDown.Value = 'R²';
            app.EditField_2 = uieditfield(app.ModelEvaluationPanel, 'text');
            app.EditField_3 = uieditfield(app.ModelEvaluationPanel, 'text');
            app.TrainErrorLabel = uilabel(app.ModelEvaluationPanel);
            app.TrainErrorLabel.Text = 'Train Error';
            app.TestErrorLabel = uilabel(app.ModelEvaluationPanel);
            app.TestErrorLabel.Text = 'Test Error';
            app.VisualizeButton_2 = uibutton(app.ModelEvaluationPanel, 'push');
            app.VisualizeButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButton_2Pushed, true);
            app.VisualizeButton_2.Text = 'Visualize ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = svr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end